classdef TapTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        STOPButton                     matlab.ui.control.Button
        Participant2Panel              matlab.ui.container.Panel
        ExportP2CSVButton              matlab.ui.control.Button
        EditField_3                    matlab.ui.control.EditField
        TrialEditField_2               matlab.ui.control.EditField
        TrialEditField_2Label          matlab.ui.control.Label
        ParticipantIDEditField_2       matlab.ui.control.EditField
        ParticipantIDEditField_2Label  matlab.ui.control.Label
        Participant1Panel              matlab.ui.container.Panel
        ExportP1CSVButton              matlab.ui.control.Button
        EditField_2                    matlab.ui.control.EditField
        TrialEditField                 matlab.ui.control.EditField
        TrialEditFieldLabel            matlab.ui.control.Label
        ParticipantIDEditField         matlab.ui.control.EditField
        ParticipantIDEditFieldLabel    matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        ProgramStatusEditField         matlab.ui.control.EditField
        ProgramStatusEditFieldLabel    matlab.ui.control.Label
        FilePathEditField              matlab.ui.control.EditField
        FilePathEditFieldLabel         matlab.ui.control.Label
        ChooseFileButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        AudioFile % The main audio file being played
        SamplingRate % The sampling rate for the input audio
        AudioPlay % Audio object
        StartStart % Datetime object that enables the audio start
        Part1Timing % Timing vector for the first participant
        Part2Timing % Timing vector for the second participant
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartStart = datetime(2000,1,1,0,0,0, 'TimeZone','local');
            app.Part1Timing = [];
            app.Part2Timing = [];
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            % opens up the file explorer and lets user choose an mp3 or wav
            try
                app.ProgramStatusEditField.Value = "Attempting to Open File. Please Wait...";
            
                [file, path] = uigetfile({'*.wav'});
                path = [path file];
                if file ~= 0
                    [app.AudioFile, app.SamplingRate] = audioread(path);
                    text = "Ready To Start Audio";
                    app.FilePathEditField.Value = path;
                    
                    % sets the time slider at the bottom
                    %app.AudioFile = [app.AudioFile; zeros(1000000,2)];
                    %seconds = size(app.AudioFile,1) / app.SamplingRate;
                    %app.Slider.Limits = [0 seconds/60];
                else
                    text = "File Not Selected. Please Try Again";
                end
                
                app.ProgramStatusEditField.Value = text;
                
            catch ME
                disp(ME);
            end
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'l'
                    if app.StartStart ~= datetime(2000,1,1,0,0,0, 'TimeZone','local')
                        temp = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
                        temp = char(temp);
                        milliTemp = 3.6e6*str2double(temp(1:2)) + 6e4*str2double(temp(4:5)) + 1e3*str2double(temp(7:8));
                        milliTemp = milliTemp + str2double(temp(10:12));

                        temp = char(app.StartStart);
                        milliStart = 3.6e6*str2double(temp(1:2)) + 6e4*str2double(temp(4:5)) + 1e3*str2double(temp(7:8));
                        milliStart = milliStart + str2double(temp(10:12));

                        app.Part2Timing = [app.Part2Timing (milliTemp-milliStart)];
                    end
                case 'a'
                    if app.StartStart ~= datetime(2000,1,1,0,0,0, 'TimeZone','local')
                        temp = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
                        temp = char(temp);
                        milliTemp = 3.6e6*str2double(temp(1:2)) + 6e4*str2double(temp(4:5)) + 1e3*str2double(temp(7:8));
                        milliTemp = milliTemp + str2double(temp(10:12));

                        temp = char(app.StartStart);
                        milliStart = 3.6e6*str2double(temp(1:2)) + 6e4*str2double(temp(4:5)) + 1e3*str2double(temp(7:8));
                        milliStart = milliStart + str2double(temp(10:12));

                        app.Part1Timing = [app.Part1Timing (milliTemp-milliStart)];
                    end
                case 'g'
                    if app.StartStart == datetime(2000,1,1,0,0,0, 'TimeZone','local')
                        try
                            app.ProgramStatusEditField.Value = "Playing Audio File";

                            app.Part1Timing = [];
                            app.Part2Timing = [];
                            playFile = app.AudioFile;
                            app.AudioPlay = audioplayer(playFile, app.SamplingRate);
                            delay = length(app.AudioFile)/app.SamplingRate;
                            
                            app.StartStart = datetime('now','TimeZone','local','Format','HH:mm:ss.SSS');
                            play(app.AudioPlay);
                            
                            pause(delay);
                            stop(app.AudioPlay);
                            app.StartStart = datetime(2000,1,1,0,0,0, 'TimeZone','local');
                            app.ProgramStatusEditField.Value = "Ready To Start Audio";
                        catch
                            app.ProgramStatusEditField.Value = "Please Choose a File Before Starting a Trial";
                        end
                    end
            end
        end

        % Button pushed function: ExportP1CSVButton
        function ExportP1CSVButtonPushed(app, event)
            if ~isempty(app.ParticipantIDEditField.Value) && ~isempty(app.TrialEditField.Value)
                mkdir("TapTimeOut/");
                filename = ['TapTimeOut/' app.ParticipantIDEditField.Value '_' app.TrialEditField.Value '_' char(datetime('now','TimeZone','local','Format','d-MMM-y')) '.csv'];
                if isfile(filename)
                    app.ProgramStatusEditField.Value = "This File Already Exists. Please Change the Participant ID or Trial #";
                else
                    writematrix(app.Part1Timing, filename);
                    app.ProgramStatusEditField.Value = "P1 Save Complete! Ready to Start Audio";
                end
            else
                app.ProgramStatusEditField.Value = "Please Input the Participant ID and Trial Number Before Saving";
            end
        end

        % Button pushed function: ExportP2CSVButton
        function ExportP2CSVButtonPushed(app, event)
            if ~isempty(app.ParticipantIDEditField_2.Value) && ~isempty(app.TrialEditField_2.Value)
                mkdir("TapTimeOut/");
                filename = ['TapTimeOut/' app.ParticipantIDEditField_2.Value '_' app.TrialEditField_2.Value '_' char(datetime('now','TimeZone','local','Format','d-MMM-y')) '.csv'];
                if isfile(filename)
                    app.ProgramStatusEditField.Value = "This File Already Exists. Please Change the Participant ID or Trial #";
                else
                    writematrix(app.Part2Timing, filename);
                    app.ProgramStatusEditField.Value = "P2 Save Complete! Ready to Start Audio";
                end
            else
                app.ProgramStatusEditField.Value = "Please Input the Participant ID and Trial Number Before Saving.";
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.StartStart ~= datetime(2000,1,1,0,0,0, 'TimeZone','local')
                stop(app.AudioPlay);
                app.StartStart = datetime(2000,1,1,0,0,0, 'TimeZone','local');
                app.ProgramStatusEditField.Value = "Ready To Start Audio";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.ProgramStatusEditFieldLabel = uilabel(app.UIFigure);
            app.ProgramStatusEditFieldLabel.Text = 'Program Status';
            app.ProgramStatusEditField = uieditfield(app.UIFigure, 'text');
            app.ProgramStatusEditField.Editable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Value = 'PRESS ''G'' TO START AUDIO';
            app.Participant1Panel = uipanel(app.UIFigure);
            app.Participant1Panel.Title = 'Participant 1';
            app.ParticipantIDEditFieldLabel = uilabel(app.Participant1Panel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.Participant1Panel, 'text');
            app.TrialEditFieldLabel = uilabel(app.Participant1Panel);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.Participant1Panel, 'text');
            app.EditField_2 = uieditfield(app.Participant1Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_2.Value = 'PRESS ''A'' TO TAP';
            app.ExportP1CSVButton = uibutton(app.Participant1Panel, 'push');
            app.ExportP1CSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportP1CSVButtonPushed, true);
            app.ExportP1CSVButton.Text = 'Export P1 CSV';
            app.Participant2Panel = uipanel(app.UIFigure);
            app.Participant2Panel.Title = 'Participant 2';
            app.ParticipantIDEditField_2Label = uilabel(app.Participant2Panel);
            app.ParticipantIDEditField_2Label.Text = 'Participant ID';
            app.ParticipantIDEditField_2 = uieditfield(app.Participant2Panel, 'text');
            app.TrialEditField_2Label = uilabel(app.Participant2Panel);
            app.TrialEditField_2Label.Text = 'Trial #';
            app.TrialEditField_2 = uieditfield(app.Participant2Panel, 'text');
            app.EditField_3 = uieditfield(app.Participant2Panel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Enable = 'off';
            app.EditField_3.Value = 'PRESS ''L'' TO TAP';
            app.ExportP2CSVButton = uibutton(app.Participant2Panel, 'push');
            app.ExportP2CSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportP2CSVButtonPushed, true);
            app.ExportP2CSVButton.Text = 'Export P2 CSV';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TapTime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end